classdef mars_rover_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MarsRoverNavigationAppUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PathPlannerTab                  matlab.ui.container.Tab
        UpdateVisualizationButton       matlab.ui.control.Button
        InputPanel                      matlab.ui.container.Panel
        SimulationPanel                 matlab.ui.container.Panel
        PlotActualRoverPathButton       matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        SimulationButton                matlab.ui.control.StateButton
        PlannerInputsPanel              matlab.ui.container.Panel
        TypeofPlannerDropDown           matlab.ui.control.DropDown
        TypeofPlannerDropDownLabel      matlab.ui.control.Label
        MinTurningRadiusmEditField      matlab.ui.control.NumericEditField
        MinTurningRadiusmEditFieldLabel  matlab.ui.control.Label
        PlanPathButton                  matlab.ui.control.Button
        PathPlannerInputsButton         matlab.ui.control.StateButton
        TerrainObstaclesPanel           matlab.ui.container.Panel
        TerrainPointsBelowSlopeAngdegSlider  matlab.ui.control.Slider
        TerrainPointsBelowSlopeAngdegSliderLabel  matlab.ui.control.Label
        DitchesCheckBox                 matlab.ui.control.CheckBox
        RocksCheckBox                   matlab.ui.control.CheckBox
        TerrainObstaclesButton          matlab.ui.control.StateButton
        OutputsButton                   matlab.ui.control.StateButton
        OutputsPanel                    matlab.ui.container.Panel
        EnergyCostJEditField            matlab.ui.control.NumericEditField
        EnergyCostJEditFieldLabel       matlab.ui.control.Label
        MaxRoverPitchAngledegEditField  matlab.ui.control.NumericEditField
        MaxRoverPitchAngledegEditFieldLabel  matlab.ui.control.Label
        TotalPathLengthmEditField       matlab.ui.control.NumericEditField
        TotalPathLengthmEditFieldLabel  matlab.ui.control.Label
        EstimatedMaxInclinationdegEditField  matlab.ui.control.NumericEditField
        EstimatedMaxInclinationdegEditFieldLabel  matlab.ui.control.Label
        MarsRoverLocationButton         matlab.ui.control.StateButton
        RoverLocationPanel              matlab.ui.container.Panel
        GoalYawAngledegEditField        matlab.ui.control.NumericEditField
        StartYawAngledegEditFieldLabel_3  matlab.ui.control.Label
        StartYawAngledegEditField       matlab.ui.control.NumericEditField
        StartYawAngledegEditFieldLabel_2  matlab.ui.control.Label
        PickGoalpointsinMapCheckBox     matlab.ui.control.CheckBox
        GoalLocationxyEditField         matlab.ui.control.EditField
        GoalLocationxyEditFieldLabel    matlab.ui.control.Label
        StartLocationxyEditField        matlab.ui.control.EditField
        StartLocationxyEditFieldLabel   matlab.ui.control.Label
        BinaryOccupancyAxes             matlab.ui.control.UIAxes
        TerrainAxes                     matlab.ui.control.UIAxes
        RoverCamsTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        LeftNavCamPanel                 matlab.ui.container.Panel
        LeftNavCamAxes                  matlab.ui.control.UIAxes
        RightNavCamPanel                matlab.ui.container.Panel
        RightNavCamAxes                 matlab.ui.control.UIAxes
        OnlinePathPlansAxes             matlab.ui.control.UIAxes
        RockDetectionAxes               matlab.ui.control.UIAxes
        RoverOutputsTab                 matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        OutputAxes_4                    matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        OutputAxes_3                    matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        OutputAxes_2                    matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        OutputAxes_1                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        map; % Description
        interpolantF;
        terrain_points_obs;
        xg;
        yg;
        z_heights;
        fx;
        fy;
        sharp_rocks_pts;
        round_rocks_pts;
        ditches_pts;
        rover_path =[];
        rocks_select;
        terrainObs_h;
        rocksObs_h;
        terrain3dObs_h;
        rocks3dObs_h;
        map_h;
        dcm_obj ;
        roverSimOut;
    end

    methods (Access = private)

        function results = RearrangePanelsUnderRoverLocation(app)

            app.MarsRoverLocationButton.Position = app.MarsRoverLocationButton.Position;
            app.RoverLocationPanel.Position = app.RoverLocationPanel.Position;
            app.MarsRoverLocationButton.Value = app.MarsRoverLocationButton.Value;

            app.TerrainObstaclesButton.Position = app.TerrainObstaclesButton.Position;
            app.TerrainObstaclesPanel.Position = app.TerrainObstaclesPanel.Position;
            ObsButtonVal = app.TerrainObstaclesButton.Value;

            app.PathPlannerInputsButton.Position = app.PathPlannerInputsButton.Position;
            app.PlannerInputsPanel.Position = app.PlannerInputsPanel.Position;
            PathButtonVal = app.PathPlannerInputsButton.Value;

            if app.MarsRoverLocationButton.Value
                app.TerrainObstaclesButton.Position(2) = app.RoverLocationPanel.Position(2) - app.TerrainObstaclesButton.Position(4);
                app.TerrainObstaclesPanel.Position(2) = app.TerrainObstaclesButton.Position(2) - app.TerrainObstaclesPanel.Position(4) ;             

            else
                app.TerrainObstaclesButton.Position(2) =  app.MarsRoverLocationButton.Position(2) - app.TerrainObstaclesButton.Position(4);
                app.TerrainObstaclesPanel.Position(2) = app.TerrainObstaclesButton.Position(2) - app.TerrainObstaclesPanel.Position(4) ;              

            end

        end
        
        function results = RearragnePanelsUnderTerrainObs(app)

            if app.TerrainObstaclesButton.Value

                app.PathPlannerInputsButton.Position(2) = app.TerrainObstaclesPanel.Position(2) - app.PathPlannerInputsButton.Position(4);
                app.PlannerInputsPanel.Position(2) =  app.PathPlannerInputsButton.Position(2) - app.PlannerInputsPanel.Position(4) ;
            else
                app.PathPlannerInputsButton.Position(2) = app.TerrainObstaclesButton.Position(2) - app.PathPlannerInputsButton.Position(4) ;
                app.PlannerInputsPanel.Position(2) = app.PathPlannerInputsButton.Position(2) - app.PlannerInputsPanel.Position(4);
            end
            
        end
        
        function results = RearragnePanelsUnderPlannerPanel(app)

            if app.PathPlannerInputsButton.Value

                app.SimulationButton.Position(2) = app.PlannerInputsPanel.Position(2) - app.SimulationButton.Position(4);
                app.SimulationPanel.Position(2) =  app.SimulationButton.Position(2) - app.SimulationPanel.Position(4) ;
            else
                app.SimulationButton.Position(2) = app.PathPlannerInputsButton.Position(2) - app.SimulationButton.Position(4) ;
                app.SimulationPanel.Position(2) = app.SimulationButton.Position(2) - app.SimulationPanel.Position(4);
            end
            
        end
        
        function results = RearragnePanelsUnderSimulationPanel(app)

            if app.SimulationButton.Value

                app.OutputsButton.Position(2) = app.SimulationPanel.Position(2) - app.OutputsButton.Position(4);
                app.OutputsPanel.Position(2) =  app.OutputsButton.Position(2) - app.OutputsPanel.Position(4) ;
            else
                app.OutputsButton.Position(2) = app.SimulationButton.Position(2) - app.OutputsButton.Position(4) ;
                app.OutputsPanel.Position(2) = app.OutputsButton.Position(2) - app.OutputsPanel.Position(4);
            end

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            RearrangePanelsUnderRoverLocation(app);
            RearragnePanelsUnderTerrainObs(app);
            RearragnePanelsUnderPlannerPanel(app);
            RearragnePanelsUnderSimulationPanel(app);


            addpath("mars_rover_helpers");
            % Remove Data tip interaction from both UI Axes. Since we
            % implement datacursor mode later.
            app.TerrainAxes.Interactions = [rotateInteraction zoomInteraction rulerPanInteraction ];
            app.BinaryOccupancyAxes.Interactions = [rotateInteraction zoomInteraction rulerPanInteraction ];
            % Turn clipping off for surf plot zoom.
            app.TerrainAxes.Clipping = 'off';

            app.rocks_select = {};
            % Expand and obtain checked boxes for rocks           
            app.rocks_select= {'Sharp Embedded Rocks';'Round Embedded Rocks';'Ditches'};          
            % Random number generator for rocks data
            rng(100,'twister');
            % createTerrainGridSurface
            [app.xg,app.yg,app.z_heights,app.interpolantF] = createTerrainGridSurface('mars_rover_data/terrain.STL');
             % Create and obtain rocks data for each type of classified rock
            [app.sharp_rocks_pts, app.round_rocks_pts, app.ditches_pts] ...
                            = createRandomRockLocations(app.xg,app.yg,app.z_heights,app.rocks_select);
           
            [app.z_heights,app.ditches_pts] = updateTerrainWithDitchesData(app.ditches_pts,app.xg,app.yg,app.z_heights);
            [app.z_heights,app.sharp_rocks_pts] = updateTerrainWithSharpRocksData(app.sharp_rocks_pts,app.xg,app.yg,app.z_heights);
            [app.z_heights,app.round_rocks_pts] = updateTerrainWithSharpRocksData(app.round_rocks_pts,app.xg,app.yg,app.z_heights);

            if app.RocksCheckBox.Value
                app.rocks_select= {'Sharp Embedded Rocks'};
            end

            if app.DitchesCheckBox.Value
                app.rocks_select = [app.rocks_select ;{'Ditches'}];
            end           

            [Xn,Yn] = ndgrid(app.xg,app.yg);

            app.interpolantF = scatteredInterpolant(Xn(:),Yn(:),app.z_heights(:));
            % Obtain terrain points which are above the allowed terrain
            % inclination angle
            [app.terrain_points_obs, app.fx, app.fy]...
                            = computeSteepTerrainPoints(app.xg,app.yg,app.z_heights,app.TerrainPointsBelowSlopeAngdegSlider.Value);          

            % Create Binary occupacny map 
            Map = createBinaryOccupancyGrid(app.xg,app.yg,...
                app.terrain_points_obs,...
                app.sharp_rocks_pts,...
                app.round_rocks_pts,...
                app.ditches_pts,...
                1.4,...%str2double(app.MapObstaclesInflationRadiusEditField.Value),...
                app.rocks_select);

            % Plot the start location and the goal locations on the occupancy map
            % and the surface plot
            app.map = Map;
            % Default XY Start and end Location
            startLocation = [5 24]; % x y
            endLocation = [19.9,17.7];            
            
            % Assign the default start and goal locations to the edit
            % fields
            formattedStartLoc = strsplit((num2str(startLocation)),' ');
            formattedStartLoc =  erase(strjoin(['[',formattedStartLoc(1),',',formattedStartLoc(2),']']),' ');

            app.StartLocationxyEditField.Value = formattedStartLoc;

            formattedGoalLoc = strsplit((num2str(endLocation)),' ');
            formattedGoalLoc =  erase(strjoin(['[',formattedGoalLoc(1),',',formattedGoalLoc(2),']']),' ');

            app.GoalLocationxyEditField.Value = formattedGoalLoc;

            % Set the default Start and Goal Yaw angle (about Z axis)
            app.StartYawAngledegEditField.Value = 0; % deg
            app.GoalYawAngledegEditField.Value = 0; % deg

            % Obtain Z coordinate for the XY start and end location using
            % the scattered interpolant
            startLocation_Z = app.interpolantF(startLocation(1),startLocation(2));
            endLocation_Z = app.interpolantF(endLocation(1),endLocation(2));
            
            startLocationXYZ = [startLocation(1:2), startLocation_Z];
            endLocationXYZ = [endLocation(1:2), endLocation_Z];

            % Plot everything on the surface and occupancy axes.
            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;

%             value = app.PickGoalpointsinMapCheckBox.Value;
%             if value
%                 set(app.TerrainAxes.Toolbar,'Visible','off')
%                 set(app.BinaryOccupancyAxes.Toolbar,'Visible','off')
% 
%                 app.dcm_obj = datacursormode(app.MarsRoverNavigationAppUIFigure);
%                 app.dcm_obj.Enable = 'on';
%                 app.dcm_obj.DisplayStyle = 'datatip';
%             else
%                 set(app.TerrainAxes.Toolbar,'Visible','on')
%                 set(app.BinaryOccupancyAxes.Toolbar,'Visible','on')
% 
%                 app.dcm_obj = datacursormode(app.MarsRoverNavigationAppUIFigure);
%                 app.dcm_obj.Enable = 'off';
%                 app.dcm_obj.DisplayStyle = 'datatip';
% 
%             end

            updateVisualization(surface_ax,map_ax,app.map,...
                             app.xg,app.yg,app.z_heights,...
                             startLocationXYZ,...
                             app.StartYawAngledegEditField.Value,...                             
                             endLocationXYZ,...
                             app.GoalYawAngledegEditField.Value,...
                             app.terrain_points_obs,...
                             app.sharp_rocks_pts,...
                             app.round_rocks_pts,...
                             app.ditches_pts,... 
                             app.rocks_select);
           assignin('base','mapp',app)


        end

        % Value changed function: TerrainObstaclesButton
        function TerrainObstaclesButtonValueChanged(app, event)
            value = app.TerrainObstaclesButton.Value;
            if value
                app.TerrainObstaclesPanel.Visible = 'on';
                app.TerrainObstaclesButton.BackgroundColor = [0.00,0.25,0.50];
                app.TerrainObstaclesButton.FontColor = [1 1 1];                
            else
                app.TerrainObstaclesPanel.Visible = 'off';
                app.TerrainObstaclesButton.BackgroundColor = [00.94,0.94,0.94];
                app.TerrainObstaclesButton.FontColor = [0 0 0];              
            end
            RearragnePanelsUnderTerrainObs(app);
            RearragnePanelsUnderPlannerPanel(app);
            RearragnePanelsUnderSimulationPanel(app);
        end

        % Value changed function: MarsRoverLocationButton
        function MarsRoverLocationButtonValueChanged(app, event)
            value = app.MarsRoverLocationButton.Value;
            if value
                app.RoverLocationPanel.Visible = 'on';
                app.MarsRoverLocationButton.BackgroundColor = [0.00,0.25,0.50];
                app.MarsRoverLocationButton.FontColor = [1 1 1];
                
            else
                app.RoverLocationPanel.Visible = 'off';
                app.MarsRoverLocationButton.BackgroundColor = [00.94,0.94,0.94];
                app.MarsRoverLocationButton.FontColor = [0 0 0];             
              
            end
            RearrangePanelsUnderRoverLocation(app);
            RearragnePanelsUnderTerrainObs(app);
            RearragnePanelsUnderPlannerPanel(app);
            RearragnePanelsUnderSimulationPanel(app);


        end

        % Value changed function: PathPlannerInputsButton
        function PathPlannerInputsButtonValueChanged(app, event)
            value = app.PathPlannerInputsButton.Value;

            if value
                app.PlannerInputsPanel.Visible = 'on';
                app.PathPlannerInputsButton.BackgroundColor = [0.00,0.25,0.50];
                app.PathPlannerInputsButton.FontColor = [1 1 1];
                
            else
                app.PlannerInputsPanel.Visible = 'off';
                app.PathPlannerInputsButton.BackgroundColor = [00.94,0.94,0.94];
                app.PathPlannerInputsButton.FontColor = [0 0 0];
            end
            RearragnePanelsUnderPlannerPanel(app);
            RearragnePanelsUnderSimulationPanel(app);
        end

        % Value changed function: SimulationButton
        function SimulationButtonValueChanged(app, event)
            value = app.SimulationButton.Value;
            if value
                app.SimulationPanel.Visible = 'on';
                app.SimulationButton.BackgroundColor = [0.00,0.25,0.50];
                app.SimulationButton.FontColor = [1 1 1];
            else
                app.SimulationPanel.Visible = 'off';
                app.SimulationButton.BackgroundColor = [00.94,0.94,0.94];
                app.SimulationButton.FontColor = [0 0 0];
            end
            RearragnePanelsUnderSimulationPanel(app);
        end

        % Value changed function: OutputsButton
        function OutputsButtonValueChanged(app, event)
            value = app.OutputsButton.Value;
            if value
                app.OutputsPanel.Visible = 'on';
                app.OutputsButton.BackgroundColor = [0.00,0.25,0.50];
                app.OutputsButton.FontColor = [1 1 1];
            else
                app.OutputsPanel.Visible = 'off';
                app.OutputsButton.BackgroundColor = [00.94,0.94,0.94];
                app.OutputsButton.FontColor = [0 0 0];
            end
        end

        % Button pushed function: UpdateVisualizationButton
        function UpdateVisualizationButtonPushed(app, event)
            if ~strcmp(event.Source.Tag,'MaxSlopeAng') && ~strcmp(event.Source.Tag,'RocksTree') &&...
                    ~strcmp(event.Source.Tag,'Simulate') 

                if isempty(app.dcm_obj)
                    if isempty(app.StartLocationxyEditField.Value)
                        startLocation = [51 39];
                        endLocation = [60 44];
                    end
                else
                    data = app.dcm_obj.getCursorInfo;
                    if ~isempty(data) 
                        if length(data)~=1
                            error("Invalid number of points choosen. Choose any 2 points before updating data.")
                        else
                           % startLocationXYZ = [data(end).Position(1:2) app.interpolantF(data(end).Position(1:2))];
                            endLocationXYZ = [data(end).Position(1:2) app.interpolantF(data(end).Position(1:2))];

                           % startLocation = startLocationXYZ(1:2);
                            endLocation = endLocationXYZ(1:2);

                            %formattedStartLoc = strsplit((num2str(startLocation)),' ');
                            %formattedStartLoc =  erase(strjoin(['[',formattedStartLoc(1),',',formattedStartLoc(2),']']),' ');

                            %app.StartLocationxyEditField.Value = formattedStartLoc;

                            formattedGoalLoc = strsplit((num2str(endLocation)),' ');
                            formattedGoalLoc =  erase(strjoin(['[',formattedGoalLoc(1),',',formattedGoalLoc(2),']']),' ');

                            app.GoalLocationxyEditField.Value = formattedGoalLoc;
                        end
                    end
                end
            end            
          
            app.terrain_points_obs = computeSteepTerrainPoints(app.xg,app.yg,app.z_heights,app.TerrainPointsBelowSlopeAngdegSlider.Value);
            app.rocks_select = {};

            if app.RocksCheckBox.Value
                app.rocks_select= {'Sharp Embedded Rocks'};
            end

            if app.DitchesCheckBox.Value
                app.rocks_select = [app.rocks_select ;{'Ditches'}];
            end

%             if ~isempty(app.Tree.CheckedNodes)
%             app.rocks_select = {app.Tree.CheckedNodes(1:end).Text};
%             end           

            % Create Binary occupacny map 
            Map = createBinaryOccupancyGrid(app.xg,app.yg,...
                app.terrain_points_obs,...
                app.sharp_rocks_pts,...
                app.round_rocks_pts,...
                app.ditches_pts,...                
                1.4,...%str2double(app.MapObstaclesInflationRadiusEditField.Value),...
                app.rocks_select);            

            app.map = Map;

            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;
            
            startLocation = str2num(string(app.StartLocationxyEditField.Value));
            endLocation = str2num(string(app.GoalLocationxyEditField.Value));

            startLocation_Z = app.interpolantF(startLocation(1),startLocation(2));
            endLocation_Z = app.interpolantF(endLocation(1),endLocation(2));

            startLocationXYZ = [startLocation(1:2), startLocation_Z];
            endLocationXYZ = [endLocation(1:2), endLocation_Z];

            f = app.MarsRoverNavigationAppUIFigure;
            set(f, 'pointer', 'watch');
            drawnow;
            
            % Plot everything on the surface and occupancy axes. 
            cla(app.TerrainAxes);
            cla(app.BinaryOccupancyAxes);
            isGraphicsLoaded = updateVisualization(surface_ax,map_ax,app.map,...
                             app.xg,app.yg,app.z_heights,...
                             startLocationXYZ,...
                             app.StartYawAngledegEditField.Value,... 
                             endLocationXYZ,...
                             app.GoalYawAngledegEditField.Value,... 
                             app.terrain_points_obs,...
                             app.sharp_rocks_pts,...
                             app.round_rocks_pts,...
                             app.ditches_pts,... 
                             app.rocks_select);

            if isGraphicsLoaded
                set(f, 'pointer', 'arrow');
            end

            % Plot planned path 
            if ~isempty(app.rover_path) && ~strcmp(event.Source.Tag,'path_plan')

            plotPlannedPathOnSurfacePlot(surface_ax,app.rover_path);
            plotPlannedPathOnOccupancyMap(map_ax,app.rover_path);

            end        
        end

        % Value changed function: PickGoalpointsinMapCheckBox
        function PickGoalpointsinMapCheckBoxValueChanged(app, event)
            value = app.PickGoalpointsinMapCheckBox.Value;
            if value
                set(app.TerrainAxes.Toolbar,'Visible','off')
                set(app.BinaryOccupancyAxes.Toolbar,'Visible','off')

                app.dcm_obj = datacursormode(app.MarsRoverNavigationAppUIFigure);
                app.dcm_obj.Enable = 'on';
                app.dcm_obj.DisplayStyle = 'datatip';
            else
                set(app.TerrainAxes.Toolbar,'Visible','on')
                set(app.BinaryOccupancyAxes.Toolbar,'Visible','on')

                app.dcm_obj = datacursormode(app.MarsRoverNavigationAppUIFigure);
                app.dcm_obj.Enable = 'off';
                app.dcm_obj.DisplayStyle = 'datatip';

            end
        end

        % Button pushed function: PlanPathButton
        function PlanPathButtonPushed(app, event)
                        
            UpdateVisualizationButtonPushed(app, event);

            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;
            
            startLocation = str2num(string(app.StartLocationxyEditField.Value));
            endLocation = str2num(string(app.GoalLocationxyEditField.Value));

            startLocation = [startLocation(1:2) deg2rad(app.StartYawAngledegEditField.Value)];
            endLocation = [endLocation(1:2) deg2rad(app.GoalYawAngledegEditField.Value)];
            
            f = waitbar(0.5,'Finding path...','Name','Path Planner');            
            
          switch(app.TypeofPlannerDropDown.Value)
              
              case 'Hybrid A*'
                  try
                      [refpath,dir,solInfo,endLocation] = planRoverHybridAStar(app.map,...
                          startLocation,endLocation,...
                          app.MinTurningRadiusmEditField.Value);                      
                      
                      if ~solInfo.IsPathFound
                          errordlg('Could not find a valid path. Change the desired pose or planner parameters.')
                          delete(f);
                          return;
                      else
                          if any(dir == -1)
                              warndlg('Planned path has reverse directions. Change the desired pose or planner parameters.')
                          end
                          waitbar(1,f,'Path found');
                          
                      end
                      delete(f);
                  catch ME
                      errordlg(ME.message);                  
                      delete(f);
                      return;
                  end                                   
                   
              case 'RRT*'                 
               
                  try
                      [refpath,solInfo] = planRoverRRTStar(app.map,...
                          startLocation,endLocation,...
                          app.MinTurningRadiusmEditField.Value);

                      if ~solInfo.IsPathFound
                          delete(f)
                          errordlg('Could not find a valid path. Change the desired pose or planner parameters.')
                          return;                      
                      else
                          waitbar(1,f,'Path found');
                          
                          delete(f);
                      end

                  catch ME
                      errordlg(ME.message);
                      delete(f)
                      return;
                  end

          end                      
          
%           plotObstaclesOnOccupancyMap(map_ax,app.xg,app.yg,...
%               app.sharp_rocks_pts,...
%               app.round_rocks_pts,...
%               app.ditches_pts,...
%               app.rocks_select);

          assignin('base','map',app.map);

          app.rover_path.x = refpath.States(:,1);
          app.rover_path.y = refpath.States(:,2);
          app.rover_path.z = app.interpolantF(app.rover_path.x,...
              app.rover_path.y);

          end_loc_wrt_WF =...
              [app.rover_path.x(end) ,app.rover_path.y(end) ,...
              app.interpolantF(app.rover_path.x(end),app.rover_path.y(end))]';

%           app.GoalYawAngledegEditField.Value = rad2deg(endLocation(3));

          app.rover_path.sampleLoc = end_loc_wrt_WF + ...
              eul2rotm([0 0 endLocation(3)],'XYZ')...
              *[1.9 0 0]';

          app.rover_path.sampleLoc(3) = app.interpolantF(app.rover_path.sampleLoc(1),app.rover_path.sampleLoc(2));
          app.rover_path.t0.pzOffset = app.interpolantF(app.rover_path.x(1),app.rover_path.y(1))+0.55;
          app.rover_path.t0.yaw = deg2rad(app.StartYawAngledegEditField.Value);
          app.rover_path.t0.pitch = -0.0480;
          app.rover_path.t0.roll = 0.0147;
          app.rover_path.Offset_vis_z= 0.0500;
          app.rover_path.name = 'rover_path';


          plotPlannedPathOnSurfacePlot(surface_ax,app.rover_path);
          plotPlannedPathOnOccupancyMap(map_ax,app.rover_path);
          

          roverPath = app.rover_path;        

          angle = computePathInclinationAngles(app.rover_path);

          app.EstimatedMaxInclinationdegEditField.Value = max(angle);

          app.TotalPathLengthmEditField.Value = refpath.pathLength;

          app.MaxRoverPitchAngledegEditField.Value = 0;
          app.EnergyCostJEditField.Value = 0;

          sharp_rocks = [0 0 -1];
          round_rocks = [0 0 -1];
          ditches = [0 0 -1];

          if ismember('Sharp Embedded Rocks',app.rocks_select)
              sharp_rocks = app.sharp_rocks_pts;
          end

          if ismember('Round Embedded Rocks',app.rocks_select)
              round_rocks = app.round_rocks_pts;
          end

          if ismember('Ditches',app.rocks_select)
              ditches = app.ditches_pts;
          end          
          save(['mars_rover_data',filesep,'rover_path.mat'],'roverPath','sharp_rocks','round_rocks','ditches');          
          legend(surface_ax,'Location','northwest')
          msgbox('Planned Path saved to mars_rover_data \ rover_path.mat');
          evalin('base',...
                    '[goal_loc,roverPath,sample_position,pointCloudPath] = rover_path_select(3);');
          assignin('base','mapData',app.map.getOccupancy);
        end

        % Value changed function: RocksCheckBox
        function RocksCheckBoxValueChanged(app, event)
            value = app.RocksCheckBox.Value;
            UpdateVisualizationButtonPushed(app, event);      

        end

        % Value changed function: DitchesCheckBox
        function DitchesCheckBoxValueChanged(app, event)
            value = app.DitchesCheckBox.Value;
             UpdateVisualizationButtonPushed(app, event);      

        end

        % Value changed function: TerrainPointsBelowSlopeAngdegSlider
        function TerrainPointsBelowSlopeAngdegSliderValueChanged(app, event)
            value = app.TerrainPointsBelowSlopeAngdegSlider.Value;
             UpdateVisualizationButtonPushed(app, event);      

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            UpdateVisualizationButtonPushed(app, event);
                
                if isempty(app.rover_path)
                    %PlanPathButtonPushed(app, event);
                    errordlg('Path not found. Please plan a path before simulating')
                    return;
                end

                mdlname = 'mars_rover';
                open_system(mdlname);                
                evalin('base',...
                    'mars_rover_params;[goal_loc,roverPath,sample_position,pointCloudPath] = rover_path_select(3);');
                app.roverSimOut = sim(mdlname);
                assignin('base','sm_mars_rover_out',app.roverSimOut);
                format shortG
                roll_pitch_yaw = app.roverSimOut.logsout.getElement('RoverRollPitchYaw');
                app.MaxRoverPitchAngledegEditField.Value = max(abs(roll_pitch_yaw.Values.Data(:,2)));
                app.EnergyCostJEditField.Value = app.roverSimOut.energyCost(end);
                mars_rover_plot_rover_path_fn(app.OutputAxes_1,app.roverSimOut,app.rover_path);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MarsRoverNavigationAppUIFigure = uifigure('Visible', 'off');
            app.MarsRoverNavigationAppUIFigure.Name = 'Mars Rover Navigation App';
            app.MarsRoverNavigationAppUIFigure.Tag = 'mars_rover_app_tag';
            app.TabGroup = uitabgroup(app.MarsRoverNavigationAppUIFigure);
            app.PathPlannerTab = uitab(app.TabGroup);
            app.PathPlannerTab.Title = 'Path Planner';
            app.TerrainAxes = uiaxes(app.PathPlannerTab);
            title(app.TerrainAxes, 'Terrain')
            xlabel(app.TerrainAxes, 'X')
            ylabel(app.TerrainAxes, 'Y')
            zlabel(app.TerrainAxes, 'Z')
            app.BinaryOccupancyAxes = uiaxes(app.PathPlannerTab);
            title(app.BinaryOccupancyAxes, 'Occupancy Map')
            xlabel(app.BinaryOccupancyAxes, 'X')
            ylabel(app.BinaryOccupancyAxes, 'Y')
            zlabel(app.BinaryOccupancyAxes, 'Z')
            app.InputPanel = uipanel(app.PathPlannerTab);
            app.RoverLocationPanel = uipanel(app.InputPanel);
            app.StartLocationxyEditFieldLabel = uilabel(app.RoverLocationPanel);
            app.StartLocationxyEditFieldLabel.Enable = 'off';
            app.StartLocationxyEditFieldLabel.Visible = 'off';
            app.StartLocationxyEditFieldLabel.Text = 'Start XY Location (m)';
            app.StartLocationxyEditField = uieditfield(app.RoverLocationPanel, 'text');
            app.StartLocationxyEditField.Editable = 'off';
            app.StartLocationxyEditField.Visible = 'off';
            app.GoalLocationxyEditFieldLabel = uilabel(app.RoverLocationPanel);
            app.GoalLocationxyEditFieldLabel.Text = 'Goal XY Location (m)';
            app.GoalLocationxyEditField = uieditfield(app.RoverLocationPanel, 'text');
            app.PickGoalpointsinMapCheckBox = uicheckbox(app.RoverLocationPanel);
            app.PickGoalpointsinMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @PickGoalpointsinMapCheckBoxValueChanged, true);
            app.PickGoalpointsinMapCheckBox.Tooltip = {'To create multiple data tips, hold the Shift key while clicking the data points.'};
            app.PickGoalpointsinMapCheckBox.Text = 'Pick Goal point in Map';
            app.StartYawAngledegEditFieldLabel_2 = uilabel(app.RoverLocationPanel);
            app.StartYawAngledegEditFieldLabel_2.Enable = 'off';
            app.StartYawAngledegEditFieldLabel_2.Visible = 'off';
            app.StartYawAngledegEditFieldLabel_2.Text = 'Start Yaw Angle (deg)';
            app.StartYawAngledegEditField = uieditfield(app.RoverLocationPanel, 'numeric');
            app.StartYawAngledegEditField.Enable = 'off';
            app.StartYawAngledegEditField.Visible = 'off';
            app.StartYawAngledegEditFieldLabel_3 = uilabel(app.RoverLocationPanel);
            app.StartYawAngledegEditFieldLabel_3.Text = 'Goal Yaw Angle (deg)';
            app.GoalYawAngledegEditField = uieditfield(app.RoverLocationPanel, 'numeric');
            app.GoalYawAngledegEditField.Value = -90;
            app.MarsRoverLocationButton = uibutton(app.InputPanel, 'state');
            app.MarsRoverLocationButton.ValueChangedFcn = createCallbackFcn(app, @MarsRoverLocationButtonValueChanged, true);
            app.MarsRoverLocationButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.MarsRoverLocationButton.Text = 'Mars Rover Location';
            app.MarsRoverLocationButton.Value = true;
            app.OutputsPanel = uipanel(app.InputPanel);
            app.OutputsPanel.Visible = 'off';
            app.EstimatedMaxInclinationdegEditFieldLabel = uilabel(app.OutputsPanel);
            app.EstimatedMaxInclinationdegEditFieldLabel.Text = 'Estimated Max Inclination (deg)';
            app.EstimatedMaxInclinationdegEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.EstimatedMaxInclinationdegEditField.Editable = 'off';
            app.EstimatedMaxInclinationdegEditField.Enable = 'off';
            app.TotalPathLengthmEditFieldLabel = uilabel(app.OutputsPanel);
            app.TotalPathLengthmEditFieldLabel.Text = 'Total Path Length (m)';
            app.TotalPathLengthmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.TotalPathLengthmEditField.Editable = 'off';
            app.TotalPathLengthmEditField.Enable = 'off';
            app.MaxRoverPitchAngledegEditFieldLabel = uilabel(app.OutputsPanel);
            app.MaxRoverPitchAngledegEditFieldLabel.Text = 'Max Rover Pitch Angle (deg)';
            app.MaxRoverPitchAngledegEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.MaxRoverPitchAngledegEditField.Editable = 'off';
            app.MaxRoverPitchAngledegEditField.Enable = 'off';
            app.EnergyCostJEditFieldLabel = uilabel(app.OutputsPanel);
            app.EnergyCostJEditFieldLabel.Text = 'Energy Cost (J)';
            app.EnergyCostJEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.EnergyCostJEditField.ValueDisplayFormat = '%.2f';
            app.EnergyCostJEditField.Editable = 'off';
            app.EnergyCostJEditField.Enable = 'off';
            app.OutputsButton = uibutton(app.InputPanel, 'state');
            app.OutputsButton.ValueChangedFcn = createCallbackFcn(app, @OutputsButtonValueChanged, true);
            app.OutputsButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.OutputsButton.Text = 'Outputs';
            app.TerrainObstaclesButton = uibutton(app.InputPanel, 'state');
            app.TerrainObstaclesButton.ValueChangedFcn = createCallbackFcn(app, @TerrainObstaclesButtonValueChanged, true);
            app.TerrainObstaclesButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.TerrainObstaclesButton.Text = 'Terrain Obstacles';
            app.TerrainObstaclesPanel = uipanel(app.InputPanel);
            app.TerrainObstaclesPanel.Visible = 'off';
            app.RocksCheckBox = uicheckbox(app.TerrainObstaclesPanel);
            app.RocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @RocksCheckBoxValueChanged, true);
            app.RocksCheckBox.Text = 'Rocks';
            app.RocksCheckBox.Value = true;
            app.DitchesCheckBox = uicheckbox(app.TerrainObstaclesPanel);
            app.DitchesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DitchesCheckBoxValueChanged, true);
            app.DitchesCheckBox.Text = 'Ditches';
            app.DitchesCheckBox.Value = true;
            app.TerrainPointsBelowSlopeAngdegSliderLabel = uilabel(app.TerrainObstaclesPanel);
            app.TerrainPointsBelowSlopeAngdegSliderLabel.Text = 'Terrain Points Above Slope Ang (deg)';
            app.TerrainPointsBelowSlopeAngdegSlider = uislider(app.TerrainObstaclesPanel);
            app.TerrainPointsBelowSlopeAngdegSlider.Limits = [20 40];
            app.TerrainPointsBelowSlopeAngdegSlider.MajorTicks = [20 25 30 35 40];
            app.TerrainPointsBelowSlopeAngdegSlider.MajorTickLabels = {'20', '25', '30', '35', '40'};
            app.TerrainPointsBelowSlopeAngdegSlider.ValueChangedFcn = createCallbackFcn(app, @TerrainPointsBelowSlopeAngdegSliderValueChanged, true);
            app.TerrainPointsBelowSlopeAngdegSlider.MinorTicks = [];
            app.TerrainPointsBelowSlopeAngdegSlider.Tag = 'MaxSlopeAng';
            app.TerrainPointsBelowSlopeAngdegSlider.Value = 40;
            app.PathPlannerInputsButton = uibutton(app.InputPanel, 'state');
            app.PathPlannerInputsButton.ValueChangedFcn = createCallbackFcn(app, @PathPlannerInputsButtonValueChanged, true);
            app.PathPlannerInputsButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.PathPlannerInputsButton.Text = 'Path Planner Inputs';
            app.PlannerInputsPanel = uipanel(app.InputPanel);
            app.PlannerInputsPanel.Visible = 'off';
            app.PlanPathButton = uibutton(app.PlannerInputsPanel, 'push');
            app.PlanPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanPathButtonPushed, true);
            app.PlanPathButton.Tag = 'path_plan';
            app.PlanPathButton.Text = 'Plan Path';
            app.MinTurningRadiusmEditFieldLabel = uilabel(app.PlannerInputsPanel);
            app.MinTurningRadiusmEditFieldLabel.Text = 'Min Turning Radius (m)';
            app.MinTurningRadiusmEditField = uieditfield(app.PlannerInputsPanel, 'numeric');
            app.MinTurningRadiusmEditField.Tooltip = {'This parameter changes the minTurningRadius property of the planner.'};
            app.MinTurningRadiusmEditField.Value = 1.5;
            app.TypeofPlannerDropDownLabel = uilabel(app.PlannerInputsPanel);
            app.TypeofPlannerDropDownLabel.Enable = 'off';
            app.TypeofPlannerDropDownLabel.Visible = 'off';
            app.TypeofPlannerDropDownLabel.Text = 'Type of Planner';
            app.TypeofPlannerDropDown = uidropdown(app.PlannerInputsPanel);
            app.TypeofPlannerDropDown.Items = {'Hybrid A*', 'RRT*'};
            app.TypeofPlannerDropDown.Enable = 'off';
            app.TypeofPlannerDropDown.Visible = 'off';
            app.TypeofPlannerDropDown.Value = 'Hybrid A*';
            app.SimulationButton = uibutton(app.InputPanel, 'state');
            app.SimulationButton.ValueChangedFcn = createCallbackFcn(app, @SimulationButtonValueChanged, true);
            app.SimulationButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.SimulationButton.Text = 'Simulation';
            app.SimulationPanel = uipanel(app.InputPanel);
            app.SimulationPanel.Visible = 'off';
            app.SimulateButton = uibutton(app.SimulationPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Tag = 'simulate';
            app.SimulateButton.Text = 'Simulate';
            app.PlotActualRoverPathButton = uibutton(app.SimulationPanel, 'push');
            app.PlotActualRoverPathButton.Text = 'Plot Actual Rover Path';
            app.UpdateVisualizationButton = uibutton(app.PathPlannerTab, 'push');
            app.UpdateVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVisualizationButtonPushed, true);
            app.UpdateVisualizationButton.Text = 'Update Visualization ';
            app.RoverCamsTab = uitab(app.TabGroup);
            app.RoverCamsTab.Title = 'Rover Cams';
            app.GridLayout = uigridlayout(app.RoverCamsTab);
            app.RockDetectionAxes = uiaxes(app.GridLayout);
            title(app.RockDetectionAxes, 'Rock Detection')
            zlabel(app.RockDetectionAxes, 'Z')
            app.OnlinePathPlansAxes = uiaxes(app.GridLayout);
            title(app.OnlinePathPlansAxes, 'Online Path Plans')
            xlabel(app.OnlinePathPlansAxes, 'X')
            ylabel(app.OnlinePathPlansAxes, 'Y')
            zlabel(app.OnlinePathPlansAxes, 'Z')
            app.RightNavCamPanel = uipanel(app.GridLayout);
            app.RightNavCamPanel.Title = 'RightNavCam';
            app.RightNavCamAxes = uiaxes(app.RightNavCamPanel);
            title(app.RightNavCamAxes, 'Title')
            xlabel(app.RightNavCamAxes, 'X')
            ylabel(app.RightNavCamAxes, 'Y')
            zlabel(app.RightNavCamAxes, 'Z')
            app.LeftNavCamPanel = uipanel(app.GridLayout);
            app.LeftNavCamPanel.Title = 'LeftNavCam';
            app.LeftNavCamAxes = uiaxes(app.LeftNavCamPanel);
            title(app.LeftNavCamAxes, 'Title')
            xlabel(app.LeftNavCamAxes, 'X')
            ylabel(app.LeftNavCamAxes, 'Y')
            zlabel(app.LeftNavCamAxes, 'Z')
            app.RoverOutputsTab = uitab(app.TabGroup);
            app.RoverOutputsTab.Title = 'Rover Outputs';
            app.Panel = uipanel(app.RoverOutputsTab);
            app.OutputAxes_1 = uiaxes(app.Panel);
            title(app.OutputAxes_1, 'Title')
            xlabel(app.OutputAxes_1, 'X')
            ylabel(app.OutputAxes_1, 'Y')
            zlabel(app.OutputAxes_1, 'Z')
            app.Panel_2 = uipanel(app.RoverOutputsTab);
            app.OutputAxes_2 = uiaxes(app.Panel_2);
            title(app.OutputAxes_2, 'Title')
            xlabel(app.OutputAxes_2, 'X')
            ylabel(app.OutputAxes_2, 'Y')
            zlabel(app.OutputAxes_2, 'Z')
            app.Panel_3 = uipanel(app.RoverOutputsTab);
            app.OutputAxes_3 = uiaxes(app.Panel_3);
            title(app.OutputAxes_3, 'Title')
            xlabel(app.OutputAxes_3, 'X')
            ylabel(app.OutputAxes_3, 'Y')
            zlabel(app.OutputAxes_3, 'Z')
            app.Panel_4 = uipanel(app.RoverOutputsTab);
            app.OutputAxes_4 = uiaxes(app.Panel_4);
            title(app.OutputAxes_4, 'Title')
            xlabel(app.OutputAxes_4, 'X')
            ylabel(app.OutputAxes_4, 'Y')
            zlabel(app.OutputAxes_4, 'Z')
            % Show the figure after all components are created
            app.MarsRoverNavigationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mars_rover_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MarsRoverNavigationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MarsRoverNavigationAppUIFigure)
        end
    end
end