classdef mars_rover_app_exercises < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PathPlannerTab                  matlab.ui.container.Tab
        UpdateVisualizationButton       matlab.ui.control.Button
        InputPanel                      matlab.ui.container.Panel
        Exercise1Button                 matlab.ui.control.StateButton
        Exercise1Panel                  matlab.ui.container.Panel
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        PlanaEgressPathButton           matlab.ui.control.Button
        GoalLocationEditFieldEx1        matlab.ui.control.EditField
        GoalLocationEditFieldLabelEx1   matlab.ui.control.Label
        CameraPitchEditField            matlab.ui.control.EditField
        StartLocationEditFieldLabelEx1  matlab.ui.control.Label
        CameraPanEditField2             matlab.ui.control.EditField
        CameraPanEditLabel2             matlab.ui.control.Label
        CameraPanEditField1             matlab.ui.control.EditField
        CameraPanLabel                  matlab.ui.control.Label
        CameraPitchEditFieldLabel       matlab.ui.control.Label
        Ex1DropDown                     matlab.ui.control.DropDown
        SimulateButtonEx1               matlab.ui.control.Button
        Exercise2Button                 matlab.ui.control.StateButton
        Exercise2Panel                  matlab.ui.container.Panel
        SimulateButton_3                matlab.ui.control.Button
        DNNParametersButton             matlab.ui.control.Button
        IncludeknownterrainobstaclesCheckBox_2  matlab.ui.control.CheckBox
        OutputsButton                   matlab.ui.control.StateButton
        OutputsPanel                    matlab.ui.container.Panel
        EnergyCostJEditField            matlab.ui.control.NumericEditField
        EnergyCostJEditFieldLabel       matlab.ui.control.Label
        MaxRoverPitchAngledegEditField  matlab.ui.control.NumericEditField
        MaxRoverPitchAngledegEditFieldLabel  matlab.ui.control.Label
        TotalPathLengthmEditField       matlab.ui.control.NumericEditField
        TotalPathLengthmEditFieldLabel  matlab.ui.control.Label
        EstimatedMaxInclinationdegEditField  matlab.ui.control.NumericEditField
        EstimatedMaxInclinationdegEditFieldLabel  matlab.ui.control.Label
        Exercise3Button                 matlab.ui.control.StateButton
        Exercise3Panel                  matlab.ui.container.Panel
        VisualizeunknownrocksCheckBox   matlab.ui.control.CheckBox
        SimulateButton_2                matlab.ui.control.Button
        PlanPathButton_2                matlab.ui.control.Button
        TerrainPointsBelowSlopeAngdegSlider  matlab.ui.control.Slider
        TerrainPointsBelowSlopeAngdegSliderLabel  matlab.ui.control.Label
        IncludeknownterrainobstaclesCheckBox  matlab.ui.control.CheckBox
        PickGoalpointsinMapCheckBox     matlab.ui.control.CheckBox
        GoalLocationxyEditField         matlab.ui.control.EditField
        GoalLocationxyEditFieldLabel    matlab.ui.control.Label
        StartLocationxyEditField        matlab.ui.control.EditField
        StartLocationxyEditFieldLabel   matlab.ui.control.Label
        BinaryOccupancyAxes             matlab.ui.control.UIAxes
        TerrainAxes                     matlab.ui.control.UIAxes
        RoverCamsTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        LeftNavCamPanel                 matlab.ui.container.Panel
        LeftNavCamAxes                  matlab.ui.control.UIAxes
        RightNavCamPanel                matlab.ui.container.Panel
        RightNavCamAxes                 matlab.ui.control.UIAxes
        RockDetectionAxes               matlab.ui.control.UIAxes
        OnlinePathPlansAxes             matlab.ui.control.UIAxes
        RoverOutputsTab                 matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        UIAxes_7                        matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        map; % Description
        interpolantF;
        terrain_points_obs;
        xg;
        yg;
        z_heights;
        fx;
        fy;
        sharp_rocks_pts;
        round_rocks_pts;
        ditches_pts;
        rover_path =[];
        rocks_select;
        terrainObs_h;
        rocksObs_h;
        terrain3dObs_h;
        rocks3dObs_h;
        map_h;
        dcm_obj ;
        roverSimOut;
    end

    methods (Access = private)

        function results = RearrangePanelsUnderEx1Panel(app)

            if app.Exercise1Button.Value

                app.Exercise2Button.Position(2) = app.Exercise1Panel.Position(2) - app.Exercise2Button.Position(4);
                app.Exercise2Panel.Position(2) =  app.Exercise2Button.Position(2) - app.Exercise2Panel.Position(4) ;
            else
                app.Exercise2Button.Position(2) = app.Exercise1Button.Position(2) - app.Exercise2Button.Position(4) ;
                app.Exercise2Panel.Position(2) = app.Exercise2Button.Position(2) - app.Exercise2Panel.Position(4);
            end

        end
        
        function results = RearragnePanelsUnderEx2Panel(app)

            if app.Exercise2Button.Value

                app.Exercise3Button.Position(2) = app.Exercise2Panel.Position(2) - app.Exercise3Button.Position(4);
                app.Exercise3Panel.Position(2) =  app.Exercise3Button.Position(2) - app.Exercise3Panel.Position(4) ;
            else
                app.Exercise3Button.Position(2) = app.Exercise2Button.Position(2) - app.Exercise3Button.Position(4) ;
                app.Exercise3Panel.Position(2) = app.Exercise3Button.Position(2) - app.Exercise3Panel.Position(4);
            end
            
        end       
       
        
        function results = RearragnePanelsUnderEx3Panel(app)

            if app.Exercise3Button.Value

                app.OutputsButton.Position(2) = app.Exercise3Panel.Position(2) - app.OutputsButton.Position(4);
                app.OutputsPanel.Position(2) =  app.OutputsButton.Position(2) - app.OutputsPanel.Position(4) ;
            else
                app.OutputsButton.Position(2) = app.Exercise3Button.Position(2) - app.OutputsButton.Position(4) ;
                app.OutputsPanel.Position(2) = app.OutputsButton.Position(2) - app.OutputsPanel.Position(4);
            end

            
        end
        
        function results = RearrangeEx1PanelDropdown(app)

            value = app.Ex1DropDown.Value;

            if strcmp(value,'Calibrate Sensors')
                app.GoalLocationEditFieldEx1.Visible = "off";
                app.StartLocationEditFieldEx1.Visible = "off";
                app.PlanaEgressPathButton.Visible = "off";

                app.GoalLocationEditFieldLabelEx1.Visible = "off";
                app.StartLocationEditFieldLabelEx1.Visible = "off";                
                
                app.GoalLocationEditFieldEx1.Enable = "off";
                app.StartLocationEditFieldEx1.Enable = "off";
                app.PlanaEgressPathButton.Enable = "off";


                app.CameraPanEditField1.Visible = "on";
                app.CameraPanEditField2.Visible = "on";
                app.CameraPanEditLabel2.Visible = "on";
                app.CameraPanLabel.Visible = "on";

                app.CameraPitchEditField.Visible = "on";
                app.CameraPitchEditFieldLabel.Visible = "on";                

            else

                app.GoalLocationEditFieldEx1.Visible = "on";
                app.StartLocationEditFieldEx1.Visible = "on";
                app.PlanaEgressPathButton.Visible = "on";

                app.GoalLocationEditFieldLabelEx1.Visible = "on";
                app.StartLocationEditFieldLabelEx1.Visible = "on";                
                
                app.GoalLocationEditFieldEx1.Enable = "on";
                app.StartLocationEditFieldEx1.Enable = "on";
                app.PlanaEgressPathButton.Enable = "on";

                app.CameraPanEditField1.Visible = "off";
                app.CameraPanEditField2.Visible = "off";
                app.CameraPanEditLabel2.Visible = "off";
                app.CameraPanLabel.Visible = "off";

                app.CameraPitchEditField.Visible = "off";
                app.CameraPitchEditFieldLabel.Visible = "off"; 


            end
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            RearrangePanelsUnderEx1Panel(app);
            RearragnePanelsUnderEx2Panel(app);
            RearragnePanelsUnderEx3Panel(app);
            RearrangeEx1PanelDropdown(app)

            addpath("mars_rover_helpers");
            % Remove Data tip interaction from both UI Axes. Since we
            % implement datacursor mode later.
            app.TerrainAxes.Interactions = [rotateInteraction zoomInteraction rulerPanInteraction ];
            app.BinaryOccupancyAxes.Interactions = [rotateInteraction zoomInteraction rulerPanInteraction ];
            % Turn clipping off for surf plot zoom.
            app.TerrainAxes.Clipping = 'off';
          
            % Expand and obtain checked boxes for rocks           
            app.rocks_select= {'Sharp Embedded Rocks';'Round Embedded Rocks';'Ditches'};          
            % Random number generator for rocks data
            rng(100,'twister');
            % createTerrainGridSurface
            [app.xg,app.yg,app.z_heights,app.interpolantF] = createTerrainGridSurface('mars_rover_data/terrain.STL');
             % Create and obtain rocks data for each type of classified rock
            [app.sharp_rocks_pts, app.round_rocks_pts, app.ditches_pts] ...
                            = createRandomRockLocations(app.xg,app.yg,app.z_heights,app.rocks_select);
           
            [app.z_heights,app.ditches_pts] = updateTerrainWithDitchesData(app.ditches_pts,app.xg,app.yg,app.z_heights);
            [app.z_heights,app.sharp_rocks_pts] = updateTerrainWithSharpRocksData(app.sharp_rocks_pts,app.xg,app.yg,app.z_heights);
            [app.z_heights,app.round_rocks_pts] = updateTerrainWithSharpRocksData(app.round_rocks_pts,app.xg,app.yg,app.z_heights);

%             if app.IncludeknownterrainobstaclesCheckBox.Value
%                 app.rocks_select= {'Sharp Embedded Rocks'};
%             end
% 
%             if app.DitchesCheckBox.Value
%                 app.rocks_select = [app.rocks_select ;{'Ditches'}];
%             end           

            [Xn,Yn] = ndgrid(app.xg,app.yg);

            app.interpolantF = scatteredInterpolant(Xn(:),Yn(:),app.z_heights(:));
            % Obtain terrain points which are above the allowed terrain
            % inclination angle
            [app.terrain_points_obs, app.fx, app.fy]...
                            = computeSteepTerrainPoints(app.xg,app.yg,app.z_heights,app.TerrainPointsBelowSlopeAngdegSlider.Value);          

            % Create Binary occupacny map 
            Map = createBinaryOccupancyGrid(app.xg,app.yg,...
                app.terrain_points_obs,...
                app.sharp_rocks_pts,...
                app.round_rocks_pts,...
                app.ditches_pts,...
                1.4,...%str2double(app.MapObstaclesInflationRadiusEditField.Value),...
                app.rocks_select);

            % Plot the start location and the goal locations on the occupancy map
            % and the surface plot
            app.map = Map;
            % Default XY Start and end Location
            startLocation = [5 24 0]; % x y theta
            endLocation = [9 24 0];            
            
            % Assign the default start and goal locations to the edit
            % fields
            formattedStartLoc = strsplit((num2str(startLocation)),' ');
            formattedStartLoc =  erase(strjoin(['[',formattedStartLoc(1),',',formattedStartLoc(2),',',formattedStartLoc(3),']']),' ');

            app.StartLocationEditFieldEx1.Value = formattedStartLoc;

            formattedGoalLoc = strsplit((num2str(endLocation)),' ');
            formattedGoalLoc =  erase(strjoin(['[',formattedGoalLoc(1),',',formattedGoalLoc(2),',',formattedGoalLoc(3),']']),' ');

            app.GoalLocationEditFieldEx1.Value = formattedGoalLoc;
           
            % Obtain Z coordinate for the XY start and end location using
            % the scattered interpolant
            startLocation_Z = app.interpolantF(startLocation(1),startLocation(2));
            endLocation_Z = app.interpolantF(endLocation(1),endLocation(2));
            
            startLocationXYZ = [startLocation(1:2), startLocation_Z];
            startLocationYaw = startLocation(3);
            endLocationXYZ = [endLocation(1:2), endLocation_Z];
            endLocationYaw = endLocation(3);

            % Plot everything on the surface and occupancy axes.
            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;

%             value = app.PickGoalpointsinMapCheckBox.Value;
%             if value
%                 set(app.TerrainAxes.Toolbar,'Visible','off')
%                 set(app.BinaryOccupancyAxes.Toolbar,'Visible','off')
% 
%                 app.dcm_obj = datacursormode(app.UIFigure);
%                 app.dcm_obj.Enable = 'on';
%                 app.dcm_obj.DisplayStyle = 'datatip';
%             else
%                 set(app.TerrainAxes.Toolbar,'Visible','on')
%                 set(app.BinaryOccupancyAxes.Toolbar,'Visible','on')
% 
%                 app.dcm_obj = datacursormode(app.UIFigure);
%                 app.dcm_obj.Enable = 'off';
%                 app.dcm_obj.DisplayStyle = 'datatip';
% 
%             end

            updateVisualization(surface_ax,map_ax,app.map,...
                             app.xg,app.yg,app.z_heights,...
                             startLocationXYZ,...
                             startLocationYaw,...                             
                             endLocationXYZ,...
                             endLocationYaw,...
                             app.terrain_points_obs,...
                             app.sharp_rocks_pts,...
                             app.round_rocks_pts,...
                             app.ditches_pts,... 
                             app.rocks_select);


        end

        % Value changed function: Exercise2Button
        function Exercise2ButtonValueChanged(app, event)
            value = app.Exercise2Button.Value;
            if value
                app.Exercise2Panel.Visible = 'on';
                app.Exercise2Button.BackgroundColor = [0.00,0.25,0.50];
                app.Exercise2Button.FontColor = [1 1 1];                
            else
                app.Exercise2Panel.Visible = 'off';
                app.Exercise2Button.BackgroundColor = [00.94,0.94,0.94];
                app.Exercise2Button.FontColor = [0 0 0];              
            end
            RearragnePanelsUnderEx2Panel(app);
%             RearragnePanelsUnderEx3Panel(app);
            RearragnePanelsUnderEx3Panel(app);
        end

        % Value changed function: Exercise3Button
        function Exercise3ButtonValueChanged(app, event)
            value = app.Exercise3Button.Value;
            if value
                app.Exercise3Panel.Visible = 'on';
                app.Exercise3Button.BackgroundColor = [0.00,0.25,0.50];
                app.Exercise3Button.FontColor = [1 1 1];
                
            else
                app.Exercise3Panel.Visible = 'off';
                app.Exercise3Button.BackgroundColor = [00.94,0.94,0.94];
                app.Exercise3Button.FontColor = [0 0 0];             
              
            end
%             RearrangePanelsUnderEx1Panel(app);
%             RearragnePanelsUnderEx2Panel(app);
%             RearragnePanelsUnderEx3Panel(app);
            RearragnePanelsUnderEx3Panel(app);


        end

        % Value changed function: Exercise1Button
        function Exercise1ButtonValueChanged(app, event)
            value = app.Exercise1Button.Value;

            if value
                app.Exercise1Panel.Visible = 'on';
                app.Exercise1Button.BackgroundColor = [0.00,0.25,0.50];
                app.Exercise1Button.FontColor = [1 1 1];
                
            else
                app.Exercise1Panel.Visible = 'off';
                app.Exercise1Button.BackgroundColor = [00.94,0.94,0.94];
                app.Exercise1Button.FontColor = [0 0 0];
            end
            RearrangePanelsUnderEx1Panel(app);
            RearragnePanelsUnderEx2Panel(app);
            RearragnePanelsUnderEx3Panel(app);

        end

        % Callback function
        function SimulationButtonValueChanged(app, event)
            value = app.SimulationButton.Value;
            if value
                app.SimulationPanel.Visible = 'on';
                app.SimulationButton.BackgroundColor = [0.00,0.25,0.50];
                app.SimulationButton.FontColor = [1 1 1];
            else
                app.SimulationPanel.Visible = 'off';
                app.SimulationButton.BackgroundColor = [00.94,0.94,0.94];
                app.SimulationButton.FontColor = [0 0 0];
            end
            RearragnePanelsUnderEx3Panel(app);
        end

        % Value changed function: OutputsButton
        function OutputsButtonValueChanged(app, event)
            value = app.OutputsButton.Value;
            if value
                app.OutputsPanel.Visible = 'on';
                app.OutputsButton.BackgroundColor = [0.00,0.25,0.50];
                app.OutputsButton.FontColor = [1 1 1];
            else
                app.OutputsPanel.Visible = 'off';
                app.OutputsButton.BackgroundColor = [00.94,0.94,0.94];
                app.OutputsButton.FontColor = [0 0 0];
            end
        end

        % Button pushed function: UpdateVisualizationButton
        function UpdateVisualizationButtonPushed(app, event)
            if ~strcmp(event.Source.Tag,'MaxSlopeAng') && ~strcmp(event.Source.Tag,'RocksTree') &&...
                    ~strcmp(event.Source.Tag,'Simulate') 

                if isempty(app.dcm_obj)
                    if isempty(app.StartLocationxyEditField.Value)
                        startLocation = [51 39];
                        endLocation = [60 44];
                    end
                else
                    data = app.dcm_obj.getCursorInfo;
                    if ~isempty(data) 
                        if length(data)~=1
                            error("Invalid number of points choosen. Choose any 2 points before updating data.")
                        else
                           % startLocationXYZ = [data(end).Position(1:2) app.interpolantF(data(end).Position(1:2))];
                            endLocationXYZ = [data(end).Position(1:2) app.interpolantF(data(end).Position(1:2))];

                           % startLocation = startLocationXYZ(1:2);
                            endLocation = endLocationXYZ(1:2);

                            %formattedStartLoc = strsplit((num2str(startLocation)),' ');
                            %formattedStartLoc =  erase(strjoin(['[',formattedStartLoc(1),',',formattedStartLoc(2),']']),' ');

                            %app.StartLocationxyEditField.Value = formattedStartLoc;

                            formattedGoalLoc = strsplit((num2str(endLocation)),' ');
                            formattedGoalLoc =  erase(strjoin(['[',formattedGoalLoc(1),',',formattedGoalLoc(2),']']),' ');

                            app.GoalLocationxyEditField.Value = formattedGoalLoc;
                        end
                    end
                end
            end            
          
            app.terrain_points_obs = computeSteepTerrainPoints(app.xg,app.yg,app.z_heights,app.TerrainPointsBelowSlopeAngdegSlider.Value);
            app.rocks_select = {};

            if app.IncludeknownterrainobstaclesCheckBox.Value
                app.rocks_select= {'Sharp Embedded Rocks','Ditches'};
            end

%             if app.DitchesCheckBox.Value
%                 app.rocks_select = [app.rocks_select ;{'Ditches'}];
%             end

%             if ~isempty(app.Tree.CheckedNodes)
%             app.rocks_select = {app.Tree.CheckedNodes(1:end).Text};
%             end           

            % Create Binary occupacny map 
            Map = createBinaryOccupancyGrid(app.xg,app.yg,...
                app.terrain_points_obs,...
                app.sharp_rocks_pts,...
                app.round_rocks_pts,...
                app.ditches_pts,...                
                1.4,...%str2double(app.MapObstaclesInflationRadiusEditField.Value),...
                app.rocks_select);            

            app.map = Map;

            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;
            
            startLocation = str2num(string(app.StartLocationEditFieldEx1.Value));
            endLocation = str2num(string(app.GoalLocationEditFieldEx1.Value));

            startLocation_Z = app.interpolantF(startLocation(1),startLocation(2));
            endLocation_Z = app.interpolantF(endLocation(1),endLocation(2));

            startLocationXYZ = [startLocation(1:2), startLocation_Z];
            endLocationXYZ = [endLocation(1:2), endLocation_Z];

            startLocationYaw = startLocation(3);
            endLocationYaw = endLocation(3);

            f = app.UIFigure;
            set(f, 'pointer', 'watch');
            drawnow;
            
            % Plot everything on the surface and occupancy axes. 
            cla(app.TerrainAxes);
            cla(app.BinaryOccupancyAxes);
            isGraphicsLoaded = updateVisualization(surface_ax,map_ax,app.map,...
                             app.xg,app.yg,app.z_heights,...
                             startLocationXYZ,...
                             startLocationYaw,... 
                             endLocationXYZ,...
                             endLocationYaw,... 
                             app.terrain_points_obs,...
                             app.sharp_rocks_pts,...
                             app.round_rocks_pts,...
                             app.ditches_pts,... 
                             app.rocks_select);

            if isGraphicsLoaded
                set(f, 'pointer', 'arrow');
            end

            % Plot planned path 
            if ~isempty(app.rover_path) && ~strcmp(event.Source.Tag,'path_plan')

            plotPlannedPathOnSurfacePlot(surface_ax,app.rover_path);
            plotPlannedPathOnOccupancyMap(map_ax,app.rover_path);

            end        
        end

        % Value changed function: PickGoalpointsinMapCheckBox
        function PickGoalpointsinMapCheckBoxValueChanged(app, event)
            value = app.PickGoalpointsinMapCheckBox.Value;
            if value
                set(app.TerrainAxes.Toolbar,'Visible','off')
                set(app.BinaryOccupancyAxes.Toolbar,'Visible','off')

                app.dcm_obj = datacursormode(app.UIFigure);
                app.dcm_obj.Enable = 'on';
                app.dcm_obj.DisplayStyle = 'datatip';
            else
                set(app.TerrainAxes.Toolbar,'Visible','on')
                set(app.BinaryOccupancyAxes.Toolbar,'Visible','on')

                app.dcm_obj = datacursormode(app.UIFigure);
                app.dcm_obj.Enable = 'off';
                app.dcm_obj.DisplayStyle = 'datatip';

            end
        end

        % Callback function
        function PlanPathButtonPushed(app, event)
                        
            UpdateVisualizationButtonPushed(app, event);

            surface_ax = app.TerrainAxes;
            map_ax = app.BinaryOccupancyAxes;
            
            startLocation = str2num(string(app.StartLocationxyEditField.Value));
            endLocation = str2num(string(app.GoalLocationxyEditField.Value));

            startLocation = [startLocation(1:2) deg2rad(app.StartYawAngledegEditField.Value)];
            endLocation = [endLocation(1:2) deg2rad(app.GoalYawAngledegEditField.Value)];
            
            f = waitbar(0.5,'Finding path...','Name','Path Planner');            
            
          switch(app.TypeofPlannerDropDown.Value)
              
              case 'Hybrid A*'
                  try
                      [refpath,dir,solInfo,endLocation] = planRoverHybridAStar(app.map,...
                          startLocation,endLocation,...
                          app.MinTurningRadiusmEditField.Value);                      
                      
                      if ~solInfo.IsPathFound
                          errordlg('Could not find a valid path. Change the desired pose or planner parameters.')
                          delete(f);
                          return;
                      else
                          if any(dir == -1)
                              warndlg('Planned path has reverse directions. Change the desired pose or planner parameters.')
                          end
                          waitbar(1,f,'Path found');
                          
                      end
                      delete(f);
                  catch ME
                      errordlg(ME.message);                  
                      delete(f);
                      return;
                  end                                   
                   
              case 'RRT*'                 
               
                  try
                      [refpath,solInfo] = planRoverRRTStar(app.map,...
                          startLocation,endLocation,...
                          app.MinTurningRadiusmEditField.Value);

                      if ~solInfo.IsPathFound
                          delete(f)
                          errordlg('Could not find a valid path. Change the desired pose or planner parameters.')
                          return;                      
                      else
                          waitbar(1,f,'Path found');
                          
                          delete(f);
                      end

                  catch ME
                      errordlg(ME.message);
                      delete(f)
                      return;
                  end

          end                      
          
%           plotObstaclesOnOccupancyMap(map_ax,app.xg,app.yg,...
%               app.sharp_rocks_pts,...
%               app.round_rocks_pts,...
%               app.ditches_pts,...
%               app.rocks_select);

          assignin('base','map',app.map);

          app.rover_path.x = refpath.States(:,1);
          app.rover_path.y = refpath.States(:,2);
          app.rover_path.z = app.interpolantF(app.rover_path.x,...
              app.rover_path.y);

          end_loc_wrt_WF =...
              [app.rover_path.x(end) ,app.rover_path.y(end) ,...
              app.interpolantF(app.rover_path.x(end),app.rover_path.y(end))]';

          app.GoalYawAngledegEditField.Value = rad2deg(endLocation(3));

          app.rover_path.sampleLoc = end_loc_wrt_WF + ...
              eul2rotm([0 0 endLocation(3)],'XYZ')...
              *[1.9 0 0]';

          app.rover_path.sampleLoc(3) = app.interpolantF(app.rover_path.sampleLoc(1),app.rover_path.sampleLoc(2));
          app.rover_path.t0.pzOffset = app.interpolantF(app.rover_path.x(1),app.rover_path.y(1))+0.55;
          app.rover_path.t0.yaw = deg2rad(app.StartYawAngledegEditField.Value);
          app.rover_path.t0.pitch = -0.0480;
          app.rover_path.t0.roll = 0.0147;
          app.rover_path.Offset_vis_z= 0.0500;
          app.rover_path.name = 'rover_path';


          plotPlannedPathOnSurfacePlot(surface_ax,app.rover_path);
          plotPlannedPathOnOccupancyMap(map_ax,app.rover_path);
          

          roverPath = app.rover_path;        

          angle = computePathInclinationAngles(app.rover_path);

          app.EstimatedMaxInclinationdegEditField.Value = max(angle);

          app.TotalPathLengthmEditField.Value = refpath.pathLength;

          app.MaxRoverPitchAngledegEditField.Value = 0;
          app.EnergyCostJEditField.Value = 0;

          sharp_rocks = [0 0 -1];
          round_rocks = [0 0 -1];
          ditches = [0 0 -1];

          if ismember('Sharp Embedded Rocks',app.rocks_select)
              sharp_rocks = app.sharp_rocks_pts;
          end

          if ismember('Round Embedded Rocks',app.rocks_select)
              round_rocks = app.round_rocks_pts;
          end

          if ismember('Ditches',app.rocks_select)
              ditches = app.ditches_pts;
          end          
          save(['mars_rover_data',filesep,'rover_path.mat'],'roverPath','sharp_rocks','round_rocks','ditches');          
          legend(surface_ax,'Location','northwest')
          msgbox('Planned Path saved to mars_rover_data \ rover_path.mat');
          evalin('base',...
                    '[goal_loc,roverPath,sample_position,pointCloudPath] = rover_path_select(3);');
          assignin('base','mapData',app.map.getOccupancy);
        end

        % Value changed function: IncludeknownterrainobstaclesCheckBox
        function IncludeknownterrainobstaclesCheckBoxValueChanged(app, event)
            value = app.IncludeknownterrainobstaclesCheckBox.Value;
            UpdateVisualizationButtonPushed(app, event);      

        end

        % Callback function
        function DitchesCheckBoxValueChanged(app, event)
            value = app.DitchesCheckBox.Value;
             UpdateVisualizationButtonPushed(app, event);      

        end

        % Value changed function: TerrainPointsBelowSlopeAngdegSlider
        function TerrainPointsBelowSlopeAngdegSliderValueChanged(app, event)
            value = app.TerrainPointsBelowSlopeAngdegSlider.Value;
             UpdateVisualizationButtonPushed(app, event);      

        end

        % Callback function
        function SimulateButtonPushed(app, event)
            UpdateVisualizationButtonPushed(app, event);
                
                if isempty(app.rover_path)
                    %PlanPathButtonPushed(app, event);
                    errordlg('Path not found. Please plan a path before simulating')
                    return;
                end

                mdlname = 'mars_rover';
                open_system(mdlname);                
                evalin('base',...
                    'mars_rover_params;[goal_loc,roverPath,sample_position,pointCloudPath] = rover_path_select(3);');
                app.roverSimOut = sim(mdlname);
                assignin('base','sm_mars_rover_out',app.roverSimOut);
                format shortG
                roll_pitch_yaw = app.roverSimOut.logsout.getElement('RoverRollPitchYaw');
                app.MaxRoverPitchAngledegEditField.Value = max(abs(roll_pitch_yaw.Values.Data(:,2)));
                app.EnergyCostJEditField.Value = app.roverSimOut.energyCost(end);
                mars_rover_plot_rover_path_fn(app.UIAxes,app.roverSimOut,app.rover_path);

        end

        % Value changed function: Ex1DropDown
        function Ex1DropDownValueChanged(app, event)
           RearrangeEx1PanelDropdown(app)              

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PathPlannerTab = uitab(app.TabGroup);
            app.PathPlannerTab.Title = 'Path Planner';
            app.TerrainAxes = uiaxes(app.PathPlannerTab);
            title(app.TerrainAxes, 'Terrain')
            xlabel(app.TerrainAxes, 'X')
            ylabel(app.TerrainAxes, 'Y')
            zlabel(app.TerrainAxes, 'Z')
            app.BinaryOccupancyAxes = uiaxes(app.PathPlannerTab);
            title(app.BinaryOccupancyAxes, 'Occupancy Map')
            xlabel(app.BinaryOccupancyAxes, 'X')
            ylabel(app.BinaryOccupancyAxes, 'Y')
            zlabel(app.BinaryOccupancyAxes, 'Z')
            app.InputPanel = uipanel(app.PathPlannerTab);
            app.Exercise3Panel = uipanel(app.InputPanel);
            app.StartLocationxyEditFieldLabel = uilabel(app.Exercise3Panel);
            app.StartLocationxyEditFieldLabel.Enable = 'off';
            app.StartLocationxyEditFieldLabel.Text = 'Current Location';
            app.StartLocationxyEditField = uieditfield(app.Exercise3Panel, 'text');
            app.StartLocationxyEditField.Editable = 'off';
            app.GoalLocationxyEditFieldLabel = uilabel(app.Exercise3Panel);
            app.GoalLocationxyEditFieldLabel.Text = 'Goal Location ';
            app.GoalLocationxyEditField = uieditfield(app.Exercise3Panel, 'text');
            app.PickGoalpointsinMapCheckBox = uicheckbox(app.Exercise3Panel);
            app.PickGoalpointsinMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @PickGoalpointsinMapCheckBoxValueChanged, true);
            app.PickGoalpointsinMapCheckBox.Tooltip = {'To create multiple data tips, hold the Shift key while clicking the data points.'};
            app.PickGoalpointsinMapCheckBox.Text = 'Pick Goal point in Map';
            app.IncludeknownterrainobstaclesCheckBox = uicheckbox(app.Exercise3Panel);
            app.IncludeknownterrainobstaclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeknownterrainobstaclesCheckBoxValueChanged, true);
            app.IncludeknownterrainobstaclesCheckBox.Text = ' Include known terrain obstacles';
            app.IncludeknownterrainobstaclesCheckBox.Value = true;
            app.TerrainPointsBelowSlopeAngdegSliderLabel = uilabel(app.Exercise3Panel);
            app.TerrainPointsBelowSlopeAngdegSliderLabel.Text = 'Terrain Points Above Slope Ang (deg)';
            app.TerrainPointsBelowSlopeAngdegSlider = uislider(app.Exercise3Panel);
            app.TerrainPointsBelowSlopeAngdegSlider.Limits = [20 40];
            app.TerrainPointsBelowSlopeAngdegSlider.MajorTicks = [20 25 30 35 40];
            app.TerrainPointsBelowSlopeAngdegSlider.MajorTickLabels = {'20', '25', '30', '35', '40'};
            app.TerrainPointsBelowSlopeAngdegSlider.ValueChangedFcn = createCallbackFcn(app, @TerrainPointsBelowSlopeAngdegSliderValueChanged, true);
            app.TerrainPointsBelowSlopeAngdegSlider.MinorTicks = [];
            app.TerrainPointsBelowSlopeAngdegSlider.Tag = 'MaxSlopeAng';
            app.TerrainPointsBelowSlopeAngdegSlider.Value = 40;
            app.PlanPathButton_2 = uibutton(app.Exercise3Panel, 'push');
            app.PlanPathButton_2.Tag = 'path_plan';
            app.PlanPathButton_2.Text = 'Plan Path';
            app.SimulateButton_2 = uibutton(app.Exercise3Panel, 'push');
            app.SimulateButton_2.Tag = 'simulate';
            app.SimulateButton_2.Text = 'Simulate';
            app.VisualizeunknownrocksCheckBox = uicheckbox(app.Exercise3Panel);
            app.VisualizeunknownrocksCheckBox.Text = 'Visualize unknown rocks';
            app.VisualizeunknownrocksCheckBox.Value = true;
            app.Exercise3Button = uibutton(app.InputPanel, 'state');
            app.Exercise3Button.ValueChangedFcn = createCallbackFcn(app, @Exercise3ButtonValueChanged, true);
            app.Exercise3Button.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.Exercise3Button.Text = 'Exercise 3';
            app.Exercise3Button.Value = true;
            app.OutputsPanel = uipanel(app.InputPanel);
            app.OutputsPanel.Visible = 'off';
            app.EstimatedMaxInclinationdegEditFieldLabel = uilabel(app.OutputsPanel);
            app.EstimatedMaxInclinationdegEditFieldLabel.Text = 'Estimated Max Inclination (deg)';
            app.EstimatedMaxInclinationdegEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.EstimatedMaxInclinationdegEditField.Editable = 'off';
            app.EstimatedMaxInclinationdegEditField.Enable = 'off';
            app.TotalPathLengthmEditFieldLabel = uilabel(app.OutputsPanel);
            app.TotalPathLengthmEditFieldLabel.Text = 'Total Path Length (m)';
            app.TotalPathLengthmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.TotalPathLengthmEditField.Editable = 'off';
            app.TotalPathLengthmEditField.Enable = 'off';
            app.MaxRoverPitchAngledegEditFieldLabel = uilabel(app.OutputsPanel);
            app.MaxRoverPitchAngledegEditFieldLabel.Text = 'Max Rover Pitch Angle (deg)';
            app.MaxRoverPitchAngledegEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.MaxRoverPitchAngledegEditField.Editable = 'off';
            app.MaxRoverPitchAngledegEditField.Enable = 'off';
            app.EnergyCostJEditFieldLabel = uilabel(app.OutputsPanel);
            app.EnergyCostJEditFieldLabel.Text = 'Energy Cost (J)';
            app.EnergyCostJEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.EnergyCostJEditField.ValueDisplayFormat = '%.2f';
            app.EnergyCostJEditField.Editable = 'off';
            app.EnergyCostJEditField.Enable = 'off';
            app.OutputsButton = uibutton(app.InputPanel, 'state');
            app.OutputsButton.ValueChangedFcn = createCallbackFcn(app, @OutputsButtonValueChanged, true);
            app.OutputsButton.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.OutputsButton.Text = 'Outputs';
            app.Exercise2Panel = uipanel(app.InputPanel);
            app.IncludeknownterrainobstaclesCheckBox_2 = uicheckbox(app.Exercise2Panel);
            app.IncludeknownterrainobstaclesCheckBox_2.Text = ' Include known terrain obstacles';
            app.IncludeknownterrainobstaclesCheckBox_2.Value = true;
            app.DNNParametersButton = uibutton(app.Exercise2Panel, 'push');
            app.DNNParametersButton.Tag = 'path_plan';
            app.DNNParametersButton.Text = 'DNN Parameters';
            app.SimulateButton_3 = uibutton(app.Exercise2Panel, 'push');
            app.SimulateButton_3.Tag = 'simulate';
            app.SimulateButton_3.Text = 'Simulate';
            app.Exercise2Button = uibutton(app.InputPanel, 'state');
            app.Exercise2Button.ValueChangedFcn = createCallbackFcn(app, @Exercise2ButtonValueChanged, true);
            app.Exercise2Button.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.Exercise2Button.Text = 'Exercise 2';
            app.Exercise2Button.Value = true;
            app.Exercise1Panel = uipanel(app.InputPanel);
            app.SimulateButtonEx1 = uibutton(app.Exercise1Panel, 'push');
            app.SimulateButtonEx1.Tag = 'simulate';
            app.SimulateButtonEx1.Text = 'Simulate';
            app.Ex1DropDown = uidropdown(app.Exercise1Panel);
            app.Ex1DropDown.Items = {'Calibrate Sensors', 'Calibrate Linear States'};
            app.Ex1DropDown.ValueChangedFcn = createCallbackFcn(app, @Ex1DropDownValueChanged, true);
            app.Ex1DropDown.Value = 'Calibrate Sensors';
            app.CameraPitchEditFieldLabel = uilabel(app.Exercise1Panel);
            app.CameraPitchEditFieldLabel.Text = 'Camera Pitch (deg)';
            app.CameraPanLabel = uilabel(app.Exercise1Panel);
            app.CameraPanLabel.Text = 'Camera Pan (deg)';
            app.CameraPanEditField1 = uieditfield(app.Exercise1Panel, 'text');
            app.CameraPanEditLabel2 = uilabel(app.Exercise1Panel);
            app.CameraPanEditLabel2.Text = 'to';
            app.CameraPanEditField2 = uieditfield(app.Exercise1Panel, 'text');
            app.StartLocationEditFieldLabelEx1 = uilabel(app.Exercise1Panel);
            app.StartLocationEditFieldLabelEx1.Text = 'Start Location';
            app.CameraPitchEditField = uieditfield(app.Exercise1Panel, 'text');
            app.CameraPitchEditField.Editable = 'off';
            app.GoalLocationEditFieldLabelEx1 = uilabel(app.Exercise1Panel);
            app.GoalLocationEditFieldLabelEx1.Text = 'Goal Location ';
            app.GoalLocationEditFieldEx1 = uieditfield(app.Exercise1Panel, 'text');
            app.GoalLocationEditFieldEx1.Value = '[ 9 24 0]';
            app.PlanaEgressPathButton = uibutton(app.Exercise1Panel, 'push');
            app.PlanaEgressPathButton.Tag = 'path_plan';
            app.PlanaEgressPathButton.Text = 'Plan a Egress Path';
            app.EditField = uieditfield(app.Exercise1Panel, 'numeric');
            app.EditField_2 = uieditfield(app.Exercise1Panel, 'numeric');
            app.EditField_3 = uieditfield(app.Exercise1Panel, 'numeric');
            app.Exercise1Button = uibutton(app.InputPanel, 'state');
            app.Exercise1Button.ValueChangedFcn = createCallbackFcn(app, @Exercise1ButtonValueChanged, true);
            app.Exercise1Button.Icon = fullfile(pathToMLAPP, 'mars_rover_helpers', 'expand.jpg');
            app.Exercise1Button.Text = 'Exercise 1';
            app.Exercise1Button.Value = true;
            app.UpdateVisualizationButton = uibutton(app.PathPlannerTab, 'push');
            app.UpdateVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVisualizationButtonPushed, true);
            app.UpdateVisualizationButton.Text = 'Update Visualization ';
            app.RoverCamsTab = uitab(app.TabGroup);
            app.RoverCamsTab.Title = 'Rover Cams';
            app.GridLayout = uigridlayout(app.RoverCamsTab);
            app.OnlinePathPlansAxes = uiaxes(app.GridLayout);
            title(app.OnlinePathPlansAxes, 'Online Path Plans')
            xlabel(app.OnlinePathPlansAxes, 'X')
            ylabel(app.OnlinePathPlansAxes, 'Y')
            zlabel(app.OnlinePathPlansAxes, 'Z')
            app.RockDetectionAxes = uiaxes(app.GridLayout);
            title(app.RockDetectionAxes, 'Rock Detection')
            zlabel(app.RockDetectionAxes, 'Z')
            app.RightNavCamPanel = uipanel(app.GridLayout);
            app.RightNavCamPanel.Title = 'RightNavCam';
            app.RightNavCamAxes = uiaxes(app.RightNavCamPanel);
            title(app.RightNavCamAxes, 'Title')
            xlabel(app.RightNavCamAxes, 'X')
            ylabel(app.RightNavCamAxes, 'Y')
            zlabel(app.RightNavCamAxes, 'Z')
            app.LeftNavCamPanel = uipanel(app.GridLayout);
            app.LeftNavCamPanel.Title = 'LeftNavCam';
            app.LeftNavCamAxes = uiaxes(app.LeftNavCamPanel);
            title(app.LeftNavCamAxes, 'Title')
            xlabel(app.LeftNavCamAxes, 'X')
            ylabel(app.LeftNavCamAxes, 'Y')
            zlabel(app.LeftNavCamAxes, 'Z')
            app.RoverOutputsTab = uitab(app.TabGroup);
            app.RoverOutputsTab.Title = 'Rover Outputs';
            app.Panel = uipanel(app.RoverOutputsTab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.RoverOutputsTab);
            app.Panel_2.Title = 'Panel';
            app.UIAxes_4 = uiaxes(app.Panel_2);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Panel_3 = uipanel(app.RoverOutputsTab);
            app.Panel_3.Title = 'Panel';
            app.UIAxes_7 = uiaxes(app.Panel_3);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.Panel_4 = uipanel(app.RoverOutputsTab);
            app.Panel_4.Title = 'Panel';
            app.UIAxes_6 = uiaxes(app.Panel_4);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mars_rover_app_exercises

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end